classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        proyecto3                       matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        OriginalTab                     matlab.ui.container.Tab
        graficaPotenciasOriginal        matlab.ui.control.UIAxes
        MejoradoTab                     matlab.ui.container.Tab
        graficaPotenciasMejorada        matlab.ui.control.UIAxes
        AmbosTab                        matlab.ui.container.Tab
        graficaTriangulosPotencias      matlab.ui.control.UIAxes
        ResultadosPanel                 matlab.ui.container.Panel
        factorOriginal_out              matlab.ui.control.NumericEditField
        factorMejorado_out              matlab.ui.control.NumericEditField
        FactordePotenciaLabel           matlab.ui.control.Label
        VALabel_2                       matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        VARLabel_3                      matlab.ui.control.Label
        VARLabel_2                      matlab.ui.control.Label
        VALabel_3                       matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        potenciaReactivaOriginal_out    matlab.ui.control.NumericEditField
        potenciaReactivaNueva_out       matlab.ui.control.NumericEditField
        potenciaAparenteOriginal_out    matlab.ui.control.NumericEditField
        potenciaActivaOriginal_out      matlab.ui.control.NumericEditField
        potenciaAparenteNueva_out       matlab.ui.control.NumericEditField
        potenciaActivaNueva_out         matlab.ui.control.NumericEditField
        CircuitoMejoradoLabel           matlab.ui.control.Label
        CircuitoOriginalLabel           matlab.ui.control.Label
        PotenicaReactivaLabel           matlab.ui.control.Label
        PotenciaAparenteLabel           matlab.ui.control.Label
        PotenciaActivaLabel             matlab.ui.control.Label
        PorenciareactivanecesariaLabel  matlab.ui.control.Label
        CapacitanciarequeridaadicionalLabel  matlab.ui.control.Label
        potenciaReactivaC_out           matlab.ui.control.NumericEditField
        QcEditFieldLabel                matlab.ui.control.Label
        VARLabel                        matlab.ui.control.Label
        capacitanciaRequerida_out       matlab.ui.control.NumericEditField
        CapacitanciaLabel               matlab.ui.control.Label
        FLabel                          matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        DATOSDEENTRADAPanel             matlab.ui.container.Panel
        SeleccioneeltipodevoltajeButtonGroup  matlab.ui.container.ButtonGroup
        VrmsButton                      matlab.ui.control.RadioButton
        VppButton                       matlab.ui.control.RadioButton
        capacitanciaIncial_in           matlab.ui.control.NumericEditField
        fLabel_2                        matlab.ui.control.Label
        FLabel_2                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ResistenciaLabel_3              matlab.ui.control.Label
        anguloz_in                      matlab.ui.control.NumericEditField
        moduloz_in                      matlab.ui.control.NumericEditField
        ResistenciaLabel_2              matlab.ui.control.Label
        ImpedanciaZLabel_3              matlab.ui.control.Label
        HLabel_2                        matlab.ui.control.Label
        OhmLabel                        matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        frecuencia_in                   matlab.ui.control.NumericEditField
        fLabel                          matlab.ui.control.Label
        angulov_in                      matlab.ui.control.NumericEditField
        vEditFieldLabel                 matlab.ui.control.Label
        fpn_in                          matlab.ui.control.NumericEditField
        FpnEditFieldLabel               matlab.ui.control.Label
        FactordepotenciadeseadoFpLabel  matlab.ui.control.Label
        vrms_in                         matlab.ui.control.NumericEditField
        VLabel                          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        FuentedeVoltajeVLabel           matlab.ui.control.Label
        inductancia_in                  matlab.ui.control.NumericEditField
        InductanciaLabel                matlab.ui.control.Label
        resistencia_in                  matlab.ui.control.NumericEditField
        ResistenciaLabel                matlab.ui.control.Label
        ImpedanciaZLabel_2              matlab.ui.control.Label
        TabGroup_circuitos              matlab.ui.container.TabGroup
        Circuito1                       matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Circuito2                       matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Circuito3                       matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        PotenciaenACymejoramientodefactordepotenciaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
             if isempty(app.angulov_in.Value) || isempty(app.vrms_in.Value) || isempty(app.resistencia_in.Value) || isempty(app.frecuencia_in.Value) || isempty(app.fpn_in.Value)|| isempty(app.inductancia_in.Value)
                % Mostrar un mensaje de error o realizar alguna acción adecuada
                msgbox('Por favor, ingrese todos los valores antes de calcular.', 'Error');
                return; % Detener la ejecución si falta algún valor
             else
                 if app.TabGroup_circuitos.SelectedTab == app.Circuito1                     
                    cla(app.graficaPotenciasOriginal);
                    cla(app.graficaPotenciasMejorada);
                    cla(app.graficaTriangulosPotencias);

                    if app.VrmsButton.Value
                                if app.vrms_in.Value > 109 && app.vrms_in.Value < 131
                                    voltajeCalculos = app.vrms_in.Value; 
                                else
                                    msgbox("Valor de voltaje fuera de Rango [110 - 130] V ", 'ERROR');
                                    return;
                                end
                               
                    end

                    if app.VppButton.Value
                                if app.vrms_in.Value > 189 && app.vrms_in.Value < 231
                                        voltajeCalculos = app.vrms_in.Value/sqrt(3);                               
                                else
                                    msgbox("Valor de voltaje fuera de Rango [110 - 130] V ", 'ERROR');
                                    return;
                                end
             
                     end    

                    reactanciaInductiva=app.inductancia_in.Value*2*pi*app.frecuencia_in.Value;
                    modulZ = sqrt((reactanciaInductiva)^2+(app.resistencia_in.Value)^2);
                    anguloz=rad2deg(atan(reactanciaInductiva/app.resistencia_in.Value));
                    factPotenciaInicial = cosd(anguloz);

                    app.potenciaActivaOriginal_out.Value = ((voltajeCalculos^2)/(modulZ))*factPotenciaInicial;
                    app.potenciaReactivaOriginal_out.Value = ((voltajeCalculos^2)/(modulZ))*sind(anguloz);
                    app.potenciaAparenteOriginal_out.Value = sqrt((app.potenciaActivaOriginal_out.Value)^2+(app.potenciaReactivaOriginal_out.Value)^2);
                    
                    %plot(app.graficaPotenciasOriginal,app.potenciaActivaOriginal_out.Value,0);
                    originP = [0, 0]; % punto de inicio de la flecha
                    directionP = [app.potenciaActivaOriginal_out.Value, 0]; % dirección de la flecha en el eje x
                    quiver(app.graficaPotenciasOriginal, originP(1), originP(2), directionP(1), directionP(2), "off");
                    originS = [0, 0]; % punto de inicio de la flecha
                    directionS = [app.potenciaActivaOriginal_out.Value, app.potenciaReactivaOriginal_out.Value]; % dirección de la flecha en el eje x
                    quiver(app.graficaPotenciasOriginal, originS(1), originS(2), directionS(1), directionS(2), "off");
                    originQ = [app.potenciaActivaOriginal_out.Value,0]; % punto de inicio de la flecha
                    directionQ = [0, app.potenciaReactivaOriginal_out.Value]; % dirección de la flecha en el eje x
                    quiver(app.graficaPotenciasOriginal, originQ(1), originQ(2), directionQ(1), directionQ(2), "off");
                    hold(app.graficaPotenciasOriginal, 'on');

                    if factPotenciaInicial == 1
                        msgbox("El factor de potencia actual YA es optimo", 'FELICIDADES');
                        return;
                    end
    
                    anguloNuevo=acosd(app.fpn_in.Value);
                    app.potenciaReactivaNueva_out.Value=app.potenciaActivaOriginal_out.Value*tand(anguloNuevo);
                    app.potenciaReactivaC_out.Value=app.potenciaReactivaOriginal_out.Value-app.potenciaReactivaNueva_out.Value;
                    app.potenciaAparenteNueva_out.Value=sqrt((app.potenciaActivaOriginal_out.Value)^2+(app.potenciaReactivaNueva_out.Value)^2);
                    app.potenciaActivaNueva_out.Value = app.potenciaActivaOriginal_out.Value;

                    w=app.frecuencia_in.Value*2*pi;
                    app.capacitanciaRequerida_out.Value=(app.potenciaReactivaC_out.Value/(w*(voltajeCalculos)^2))*1000000;
                    app.factorOriginal_out.Value = factPotenciaInicial;
                    app.factorMejorado_out.Value = app.fpn_in.Value;

                    originP2 = [0, 0]; % punto de inicio de la flecha
                    directionP2 = [app.potenciaActivaNueva_out.Value, 0]; % dirección de la flecha en el eje x
                    quiver(app.graficaPotenciasMejorada, originP2(1), originP2(2), directionP2(1), directionP2(2), "off");
                    originS2 = [0, 0]; % punto de inicio de la flecha
                    directionS2 = [app.potenciaActivaNueva_out.Value, app.potenciaReactivaNueva_out.Value]; % dirección de la flecha en el eje x
                    quiver(app.graficaPotenciasMejorada, originS2(1), originS2(2), directionS2(1), directionS2(2), "off");
                    originQ2 = [app.potenciaActivaNueva_out.Value,0]; % punto de inicio de la flecha
                    directionQ2 = [0, app.potenciaReactivaNueva_out.Value]; % dirección de la flecha en el eje x
                    quiver(app.graficaPotenciasMejorada, originQ2(1), originQ2(2), directionQ2(1), directionQ2(2), "off");
                    hold(app.graficaPotenciasMejorada, 'on');

                    quiver(app.graficaTriangulosPotencias, originQ2(1), originQ2(2), directionQ2(1), directionQ2(2), "off");
                    quiver(app.graficaTriangulosPotencias, originS2(1), originS2(2), directionS2(1), directionS2(2), "off");
                    quiver(app.graficaTriangulosPotencias, originP2(1), originP2(2), directionP2(1), directionP2(2), "off");
                    quiver(app.graficaTriangulosPotencias, originS(1), originS(2), directionS(1), directionS(2), "off");
                    quiver(app.graficaTriangulosPotencias, originQ(1), originQ(2), directionQ(1), directionQ(2), "off");
                    hold(app.graficaTriangulosPotencias, 'on');
                 else
                    if app.TabGroup_circuitos.SelectedTab == app.Circuito2 
                    
                        cla(app.graficaPotenciasOriginal);
                        cla(app.graficaPotenciasMejorada);
                        cla(app.graficaTriangulosPotencias);

                        if app.VrmsButton.Value
                                if app.vrms_in.Value > 109 && app.vrms_in.Value < 131
                                    voltajeCalculos = app.vrms_in.Value; 
                                else
                                    msgbox("Valor de voltaje fuera de Rango [110 - 130] V ", 'ERROR');
                                    return;
                                end
                               
                        end

                        if app.VppButton.Value
                                if app.vrms_in.Value > 189 && app.vrms_in.Value < 231
                                        voltajeCalculos = app.vrms_in.Value/sqrt(3);                               
                                else
                                    msgbox("Valor de voltaje fuera de Rango [110 - 130] V ", 'ERROR');
                                    return;
                                end
             
                        end  

    
                        reactanciaInductiva=app.inductancia_in.Value*2*pi*app.frecuencia_in.Value;
                        modulZ =(reactanciaInductiva*app.resistencia_in.Value) /sqrt((reactanciaInductiva)^2+(app.resistencia_in.Value)^2);
                        anguloz= 90-rad2deg(atan(reactanciaInductiva/app.resistencia_in.Value)); %se transforma a grados por que el calculo de reactancia esta en rad
                        factPotenciaInicial = cosd(anguloz);

                        app.potenciaActivaOriginal_out.Value = ((voltajeCalculos^2)/(modulZ))*cosd(anguloz);
                        app.potenciaReactivaOriginal_out.Value = ((voltajeCalculos^2)/(modulZ))*sind(anguloz);
                        app.potenciaAparenteOriginal_out.Value = sqrt((app.potenciaActivaOriginal_out.Value)^2+(app.potenciaReactivaOriginal_out.Value)^2);
                        
                        %plot(app.graficaPotenciasOriginal,app.potenciaActivaOriginal_out.Value,0);
                        originP = [0, 0]; % punto de inicio de la flecha
                        directionP = [app.potenciaActivaOriginal_out.Value, 0]; % dirección de la flecha en el eje x
                        quiver(app.graficaPotenciasOriginal, originP(1), originP(2), directionP(1), directionP(2), "off");
                        originS = [0, 0]; % punto de inicio de la flecha
                        directionS = [app.potenciaActivaOriginal_out.Value, app.potenciaReactivaOriginal_out.Value]; % dirección de la flecha en el eje x
                        quiver(app.graficaPotenciasOriginal, originS(1), originS(2), directionS(1), directionS(2), "off");
                        originQ = [app.potenciaActivaOriginal_out.Value,0]; % punto de inicio de la flecha
                        directionQ = [0, app.potenciaReactivaOriginal_out.Value]; % dirección de la flecha en el eje x
                        quiver(app.graficaPotenciasOriginal, originQ(1), originQ(2), directionQ(1), directionQ(2), "off");
                        hold(app.graficaPotenciasOriginal, 'on');


                        anguloNuevo=acosd(app.fpn_in.Value);
                        app.potenciaReactivaNueva_out.Value=app.potenciaActivaOriginal_out.Value*tand(anguloNuevo);
                        app.potenciaReactivaC_out.Value=app.potenciaReactivaOriginal_out.Value-app.potenciaReactivaNueva_out.Value;
                        app.potenciaAparenteNueva_out.Value=sqrt((app.potenciaActivaOriginal_out.Value)^2+(app.potenciaReactivaNueva_out.Value)^2);
                        app.potenciaActivaNueva_out.Value = app.potenciaActivaOriginal_out.Value;

                        w=app.frecuencia_in.Value*2*pi;
                        app.capacitanciaRequerida_out.Value=(app.potenciaReactivaC_out.Value/(w*(voltajeCalculos)^2))*1000000;
                        app.factorOriginal_out.Value = factPotenciaInicial;
                        app.factorMejorado_out.Value = app.fpn_in.Value;

                        originP2 = [0, 0]; % punto de inicio de la flecha
                        directionP2 = [app.potenciaActivaNueva_out.Value, 0]; % dirección de la flecha en el eje x
                        quiver(app.graficaPotenciasMejorada, originP2(1), originP2(2), directionP2(1), directionP2(2), "off");
                        originS2 = [0, 0]; % punto de inicio de la flecha
                        directionS2 = [app.potenciaActivaNueva_out.Value, app.potenciaReactivaNueva_out.Value]; % dirección de la flecha en el eje x
                        quiver(app.graficaPotenciasMejorada, originS2(1), originS2(2), directionS2(1), directionS2(2), "off");
                        originQ2 = [app.potenciaActivaNueva_out.Value,0]; % punto de inicio de la flecha
                        directionQ2 = [0, app.potenciaReactivaNueva_out.Value]; % dirección de la flecha en el eje x
                        quiver(app.graficaPotenciasMejorada, originQ2(1), originQ2(2), directionQ2(1), directionQ2(2), "off");
                        hold(app.graficaPotenciasMejorada, 'on');

                        quiver(app.graficaTriangulosPotencias, originQ2(1), originQ2(2), directionQ2(1), directionQ2(2), "off");
                        quiver(app.graficaTriangulosPotencias, originS2(1), originS2(2), directionS2(1), directionS2(2), "off");
                        quiver(app.graficaTriangulosPotencias, originP2(1), originP2(2), directionP2(1), directionP2(2), "off");
                        quiver(app.graficaTriangulosPotencias, originS(1), originS(2), directionS(1), directionS(2), "off");
                        quiver(app.graficaTriangulosPotencias, originQ(1), originQ(2), directionQ(1), directionQ(2), "off");
                        hold(app.graficaTriangulosPotencias, 'on');
                    else
                         if app.TabGroup_circuitos.SelectedTab == app.Circuito3
                            cla(app.graficaPotenciasOriginal);
                            cla(app.graficaPotenciasMejorada);
                            cla(app.graficaTriangulosPotencias);

                            if app.VrmsButton.Value
                                if app.vrms_in.Value > 109 && app.vrms_in.Value < 131
                                    voltajeCalculos = app.vrms_in.Value; 
                                else
                                    msgbox("Valor de voltaje fuera de Rango [110 - 130] V ", 'ERROR');
                                    return;
                                end
                               
                            end

                            if app.VppButton.Value
                                if app.vrms_in.Value > 189 && app.vrms_in.Value < 231
                                        voltajeCalculos = app.vrms_in.Value/sqrt(3);                               
                                else
                                    msgbox("Valor de voltaje fuera de Rango [110 - 130] V ", 'ERROR');
                                    return;
                                end
             
                            end   
        
                            modulZ = app.moduloz_in.Value;
                            anguloz= app.anguloz_in.Value; %se transforma a grados por que el calculo de reactancia esta en rad
                            factPotenciaInicial = cosd(anguloz);
    
                            app.potenciaActivaOriginal_out.Value = ((voltajeCalculos^2)/(modulZ))*cosd(anguloz);
                            app.potenciaReactivaOriginal_out.Value = ((voltajeCalculos^2)/(modulZ))*sind(anguloz);
                            app.potenciaAparenteOriginal_out.Value = sqrt((app.potenciaActivaOriginal_out.Value)^2+(app.potenciaReactivaOriginal_out.Value)^2);
                            
                            %plot(app.graficaPotenciasOriginal,app.potenciaActivaOriginal_out.Value,0);
                            originP = [0, 0]; % punto de inicio de la flecha
                            directionP = [app.potenciaActivaOriginal_out.Value, 0]; % dirección de la flecha en el eje x
                            quiver(app.graficaPotenciasOriginal, originP(1), originP(2), directionP(1), directionP(2), "off");
                            originS = [0, 0]; % punto de inicio de la flecha
                            directionS = [app.potenciaActivaOriginal_out.Value, app.potenciaReactivaOriginal_out.Value]; % dirección de la flecha en el eje x
                            quiver(app.graficaPotenciasOriginal, originS(1), originS(2), directionS(1), directionS(2), "off");
                            originQ = [app.potenciaActivaOriginal_out.Value,0]; % punto de inicio de la flecha
                            directionQ = [0, app.potenciaReactivaOriginal_out.Value]; % dirección de la flecha en el eje x
                            quiver(app.graficaPotenciasOriginal, originQ(1), originQ(2), directionQ(1), directionQ(2), "off");
                            hold(app.graficaPotenciasOriginal, 'on');
    
    
                            anguloNuevo=acosd(app.fpn_in.Value);
                            app.potenciaReactivaNueva_out.Value=app.potenciaActivaOriginal_out.Value*tand(anguloNuevo);
                            app.potenciaReactivaC_out.Value=app.potenciaReactivaOriginal_out.Value-app.potenciaReactivaNueva_out.Value;
                            app.potenciaAparenteNueva_out.Value=sqrt((app.potenciaActivaOriginal_out.Value)^2+(app.potenciaReactivaNueva_out.Value)^2);
                            app.potenciaActivaNueva_out.Value = app.potenciaActivaOriginal_out.Value;
                            w=app.frecuencia_in.Value*2*pi;
                            app.capacitanciaRequerida_out.Value=((app.potenciaReactivaC_out.Value/(w*(voltajeCalculos)^2)))*1000000-app.capacitanciaIncial_in.Value;
                            app.factorOriginal_out.Value = factPotenciaInicial;
                            app.factorMejorado_out.Value = app.fpn_in.Value;

                            originP2 = [0, 0]; % punto de inicio de la flecha
                            directionP2 = [app.potenciaActivaNueva_out.Value, 0]; % dirección de la flecha en el eje x
                            quiver(app.graficaPotenciasMejorada, originP2(1), originP2(2), directionP2(1), directionP2(2), "off");
                            originS2 = [0, 0]; % punto de inicio de la flecha
                            directionS2 = [app.potenciaActivaNueva_out.Value, app.potenciaReactivaNueva_out.Value]; % dirección de la flecha en el eje x
                            quiver(app.graficaPotenciasMejorada, originS2(1), originS2(2), directionS2(1), directionS2(2), "off");
                            originQ2 = [app.potenciaActivaNueva_out.Value,0]; % punto de inicio de la flecha
                            directionQ2 = [0, app.potenciaReactivaNueva_out.Value]; % dirección de la flecha en el eje x
                            quiver(app.graficaPotenciasMejorada, originQ2(1), originQ2(2), directionQ2(1), directionQ2(2), "off");
                            hold(app.graficaPotenciasMejorada, 'on');
                            
                            % graficacion de triangulos de potencia en un
                            % solo plano
                            quiver(app.graficaTriangulosPotencias, originQ2(1), originQ2(2), directionQ2(1), directionQ2(2), "off");
                            quiver(app.graficaTriangulosPotencias, originS2(1), originS2(2), directionS2(1), directionS2(2), "off");
                            quiver(app.graficaTriangulosPotencias, originP2(1), originP2(2), directionP2(1), directionP2(2), "off");
                            quiver(app.graficaTriangulosPotencias, originS(1), originS(2), directionS(1), directionS(2), "off");
                            quiver(app.graficaTriangulosPotencias, originQ(1), originQ(2), directionQ(1), directionQ(2), "off");
                            hold(app.graficaTriangulosPotencias, 'on');
                         end
                    end
                end
             end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.proyecto3 = uifigure('Visible', 'off');
            app.proyecto3.Name = 'MATLAB App';
            app.PotenciaenACymejoramientodefactordepotenciaLabel = uilabel(app.proyecto3);
            app.PotenciaenACymejoramientodefactordepotenciaLabel.Text = 'Potencia en AC y mejoramiento de factor de potencia';
            app.TabGroup_circuitos = uitabgroup(app.proyecto3);
            app.Circuito1 = uitab(app.TabGroup_circuitos);
            app.Circuito1.Title = 'Circuito 1';
            app.Image_2 = uiimage(app.Circuito1);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-01-15 170442.png');
            app.Circuito2 = uitab(app.TabGroup_circuitos);
            app.Circuito2.Title = 'Circuito 2';
            app.Image2 = uiimage(app.Circuito2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-01-15 at 10.13.07 PM.jpeg');
            app.Circuito3 = uitab(app.TabGroup_circuitos);
            app.Circuito3.Title = 'Circuito 3';
            app.Image3 = uiimage(app.Circuito3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-01-15 at 10.15.55 PM.jpeg');
            app.DATOSDEENTRADAPanel = uipanel(app.proyecto3);
            app.DATOSDEENTRADAPanel.Title = 'DATOS DE ENTRADA';
            app.ImpedanciaZLabel_2 = uilabel(app.DATOSDEENTRADAPanel);
            app.ImpedanciaZLabel_2.Text = 'Impedancia [Z] :';
            app.ResistenciaLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.ResistenciaLabel.Text = 'Resistencia';
            app.resistencia_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.InductanciaLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.InductanciaLabel.Text = 'Inductancia';
            app.inductancia_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.FuentedeVoltajeVLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.FuentedeVoltajeVLabel.Text = 'Fuente de Voltaje  [V] :';
            app.Label_4 = uilabel(app.DATOSDEENTRADAPanel);
            app.Label_4.Text = '∡';
            app.VLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.VLabel.Text = 'V';
            app.vrms_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.FactordepotenciadeseadoFpLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.FactordepotenciadeseadoFpLabel.Text = 'Factor de potencia deseado  [Fp] :';
            app.FpnEditFieldLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.FpnEditFieldLabel.Text = 'Fpn';
            app.fpn_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.vEditFieldLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.vEditFieldLabel.Text = 'θ v';
            app.angulov_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.fLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.fLabel.Text = 'f =';
            app.frecuencia_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.HzLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.HzLabel.Text = 'Hz';
            app.OhmLabel = uilabel(app.DATOSDEENTRADAPanel);
            app.OhmLabel.Text = 'Ohm';
            app.HLabel_2 = uilabel(app.DATOSDEENTRADAPanel);
            app.HLabel_2.Text = 'H';
            app.ImpedanciaZLabel_3 = uilabel(app.DATOSDEENTRADAPanel);
            app.ImpedanciaZLabel_3.Text = 'Impedancia [Z] :';
            app.ResistenciaLabel_2 = uilabel(app.DATOSDEENTRADAPanel);
            app.ResistenciaLabel_2.Text = 'Modulo';
            app.moduloz_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.anguloz_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.ResistenciaLabel_3 = uilabel(app.DATOSDEENTRADAPanel);
            app.ResistenciaLabel_3.Text = 'Angulo';
            app.Label_5 = uilabel(app.DATOSDEENTRADAPanel);
            app.Label_5.Text = '∡';
            app.FLabel_2 = uilabel(app.DATOSDEENTRADAPanel);
            app.FLabel_2.Text = 'μF';
            app.fLabel_2 = uilabel(app.DATOSDEENTRADAPanel);
            app.fLabel_2.Text = 'Ci =';
            app.capacitanciaIncial_in = uieditfield(app.DATOSDEENTRADAPanel, 'numeric');
            app.SeleccioneeltipodevoltajeButtonGroup = uibuttongroup(app.DATOSDEENTRADAPanel);
            app.SeleccioneeltipodevoltajeButtonGroup.Title = 'Seleccione el tipo de voltaje';
            app.VppButton = uiradiobutton(app.SeleccioneeltipodevoltajeButtonGroup);
            app.VppButton.Text = 'Vpp';
            app.VppButton.Value = true;
            app.VrmsButton = uiradiobutton(app.SeleccioneeltipodevoltajeButtonGroup);
            app.VrmsButton.Text = 'Vrms';
            app.CalcularButton = uibutton(app.proyecto3, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'CALCULAR CAPACITANCIA REQUERIDA';
            app.ResultadosPanel = uipanel(app.proyecto3);
            app.ResultadosPanel.Title = 'Resultados';
            app.FLabel = uilabel(app.ResultadosPanel);
            app.FLabel.Text = 'μF';
            app.CapacitanciaLabel = uilabel(app.ResultadosPanel);
            app.CapacitanciaLabel.Text = 'C =';
            app.capacitanciaRequerida_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.capacitanciaRequerida_out.Editable = 'off';
            app.VARLabel = uilabel(app.ResultadosPanel);
            app.VARLabel.Text = 'VAR';
            app.QcEditFieldLabel = uilabel(app.ResultadosPanel);
            app.QcEditFieldLabel.Text = 'Qc:';
            app.potenciaReactivaC_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaReactivaC_out.Editable = 'off';
            app.CapacitanciarequeridaadicionalLabel = uilabel(app.ResultadosPanel);
            app.CapacitanciarequeridaadicionalLabel.Text = 'Capacitancia requerida/adicional';
            app.PorenciareactivanecesariaLabel = uilabel(app.ResultadosPanel);
            app.PorenciareactivanecesariaLabel.Text = 'Porencia reactiva necesaria';
            app.PotenciaActivaLabel = uilabel(app.ResultadosPanel);
            app.PotenciaActivaLabel.Text = 'Potencia Activa:';
            app.PotenciaAparenteLabel = uilabel(app.ResultadosPanel);
            app.PotenciaAparenteLabel.Text = 'Potencia Aparente:';
            app.PotenicaReactivaLabel = uilabel(app.ResultadosPanel);
            app.PotenicaReactivaLabel.Text = 'Potenica Reactiva:';
            app.CircuitoOriginalLabel = uilabel(app.ResultadosPanel);
            app.CircuitoOriginalLabel.Text = 'Circuito Original';
            app.CircuitoMejoradoLabel = uilabel(app.ResultadosPanel);
            app.CircuitoMejoradoLabel.Text = 'Circuito Mejorado';
            app.potenciaActivaNueva_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaActivaNueva_out.Editable = 'off';
            app.potenciaAparenteNueva_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaAparenteNueva_out.Editable = 'off';
            app.potenciaActivaOriginal_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaActivaOriginal_out.Editable = 'off';
            app.potenciaAparenteOriginal_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaAparenteOriginal_out.Editable = 'off';
            app.potenciaReactivaNueva_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaReactivaNueva_out.Editable = 'off';
            app.potenciaReactivaOriginal_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.potenciaReactivaOriginal_out.Editable = 'off';
            app.WLabel = uilabel(app.ResultadosPanel);
            app.WLabel.Text = 'W';
            app.VALabel_3 = uilabel(app.ResultadosPanel);
            app.VALabel_3.Text = 'VA';
            app.VARLabel_2 = uilabel(app.ResultadosPanel);
            app.VARLabel_2.Text = 'VAR';
            app.VARLabel_3 = uilabel(app.ResultadosPanel);
            app.VARLabel_3.Text = 'VAR';
            app.WLabel_2 = uilabel(app.ResultadosPanel);
            app.WLabel_2.Text = 'W';
            app.VALabel_2 = uilabel(app.ResultadosPanel);
            app.VALabel_2.Text = 'VA';
            app.FactordePotenciaLabel = uilabel(app.ResultadosPanel);
            app.FactordePotenciaLabel.Text = 'Factor de Potencia:';
            app.factorMejorado_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.factorMejorado_out.Editable = 'off';
            app.factorOriginal_out = uieditfield(app.ResultadosPanel, 'numeric');
            app.factorOriginal_out.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.proyecto3);
            app.OriginalTab = uitab(app.TabGroup2);
            app.OriginalTab.Title = 'Original';
            app.graficaPotenciasOriginal = uiaxes(app.OriginalTab);
            title(app.graficaPotenciasOriginal, 'Triangulo de potencia original')
            xlabel(app.graficaPotenciasOriginal, 'Potencia Activa')
            ylabel(app.graficaPotenciasOriginal, 'Potencia Reactiva')
            zlabel(app.graficaPotenciasOriginal, 'Z')
            app.graficaPotenciasOriginal.LineStyleOrder = {'-'; '-'};
            app.MejoradoTab = uitab(app.TabGroup2);
            app.MejoradoTab.Title = 'Mejorado';
            app.graficaPotenciasMejorada = uiaxes(app.MejoradoTab);
            title(app.graficaPotenciasMejorada, 'Triangulo de potencia mejorado')
            xlabel(app.graficaPotenciasMejorada, 'Potencia Activa')
            ylabel(app.graficaPotenciasMejorada, 'Potencia Reactiva Nueva')
            zlabel(app.graficaPotenciasMejorada, 'Z')
            app.AmbosTab = uitab(app.TabGroup2);
            app.AmbosTab.Title = 'Ambos';
            app.graficaTriangulosPotencias = uiaxes(app.AmbosTab);
            title(app.graficaTriangulosPotencias, 'Triangulo de potencia mejorado')
            xlabel(app.graficaTriangulosPotencias, 'Potencia Activa')
            ylabel(app.graficaTriangulosPotencias, 'Potencia Reactiva Nueva')
            zlabel(app.graficaTriangulosPotencias, 'Z')
            % Show the figure after all components are created
            app.proyecto3.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.proyecto3)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.proyecto3)
        end
    end
end